classdef plotter < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        MessageSpinner                  matlab.ui.control.Spinner
        MessageSpinnerLabel             matlab.ui.control.Label
        MinorGridsCheckBox              matlab.ui.control.CheckBox
        MajorGridsCheckBox              matlab.ui.control.CheckBox
        FilterspanEditField             matlab.ui.control.NumericEditField
        FilterspanEditFieldLabel        matlab.ui.control.Label
        SamplespersymbolEditField       matlab.ui.control.NumericEditField
        SamplespersymbolEditFieldLabel  matlab.ui.control.Label
        RolloffEditField                matlab.ui.control.NumericEditField
        RolloffEditFieldLabel           matlab.ui.control.Label
        tauEditField                    matlab.ui.control.NumericEditField
        tauEditFieldLabel               matlab.ui.control.Label
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        tau
        beta
        span
        sps
        message
        messageUS
        h
        hh
        messagerx% Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            set(groot,'defaultAxesTickLabelInterpreter','latex');
            set(groot,'defaulttextinterpreter','latex');
            set(groot,'defaultLegendInterpreter','latex');
            
            app.tau = app.tauEditField.Value;
            app.beta = app.RolloffEditField.Value;
            app.span = app.FilterspanEditField.Value;
            app.sps = app.SamplespersymbolEditField.Value;
            app.message = 1-2*fliplr(de2bi(app.MessageSpinner.Value, 11)); %#ok<ST2NM>
            app.messageUS = upsample(app.message, app.tau*app.sps);
            app.h = rcosdesign(app.beta, app.span, app.sps, "sqrt");
            app.hh = conv(app.h, app.h);
            app.messagerx = conv(app.messageUS, app.hh);
            plot(app.UIAxes2, 0:length(app.hh)-1, app.hh)
            plot(app.UIAxes, 0:length(app.messagerx)-1, app.messagerx)
            grid(app.UIAxes, "on")
            grid(app.UIAxes2, "on")
            grid(app.UIAxes, "minor")
            grid(app.UIAxes2, "minor")
            axis(app.UIAxes, "tight")
        end

        % Value changed function: MajorGridsCheckBox
        function MajorGridsCheckBoxValueChanged(app, event)
            value = app.MajorGridsCheckBox.Value;
            if ~value
                grid(app.UIAxes, "off")
                grid(app.UIAxes2, "off")
                if app.MinorGridsCheckBox.Value
                    grid(app.UIAxes, "minor")
                    grid(app.UIAxes2, "minor")
                end
            else
                grid(app.UIAxes, "on")
                grid(app.UIAxes2, "on")
            end
        end

        % Value changed function: MinorGridsCheckBox
        function MinorGridsCheckBoxValueChanged(app, event)
            value = app.MinorGridsCheckBox.Value;
            if ~value
                grid(app.UIAxes, "off")
                grid(app.UIAxes2, "off")
                if app.MajorGridsCheckBox.Value
                    grid(app.UIAxes, "on")
                    grid(app.UIAxes2, "on")
                end
            else
                grid(app.UIAxes, "minor")
                grid(app.UIAxes2, "minor")
            end
        end

        % Value changed function: tauEditField
        function tauEditFieldValueChanged(app, event)
            app.tau = app.tauEditField.Value;
            app.messageUS = upsample(app.message, app.tau*app.sps);
            app.messagerx = conv(app.messageUS, app.hh);
            plot(app.UIAxes, 0:length(app.messagerx)-1, app.messagerx)
        end

        % Value changed function: RolloffEditField
        function RolloffEditFieldValueChanged(app, event)
            app.beta = app.RolloffEditField.Value;
            app.h = rcosdesign(app.beta, app.span, app.sps, "sqrt");
            app.hh = conv(app.h, app.h);
            app.messageUS = upsample(app.message, app.tau*app.sps);
            app.messagerx = conv(app.messageUS, app.hh);
            plot(app.UIAxes, 0:length(app.messagerx)-1, app.messagerx)
            plot(app.UIAxes2, 0:length(app.hh)-1, app.hh)
        end

        % Value changed function: SamplespersymbolEditField
        function SamplespersymbolEditFieldValueChanged(app, event)
            app.sps = app.SamplespersymbolEditField.Value;
            app.h = rcosdesign(app.beta, app.span, app.sps, "sqrt");
            app.hh = conv(app.h, app.h);
            app.messageUS = upsample(app.message, app.tau*app.sps);
            app.messagerx = conv(app.messageUS, app.hh);
            plot(app.UIAxes, 0:length(app.messagerx)-1, app.messagerx)
            plot(app.UIAxes2, 0:length(app.hh)-1, app.hh)
        end

        % Value changed function: FilterspanEditField
        function FilterspanEditFieldValueChanged(app, event)
            app.span = app.FilterspanEditField.Value;
            app.h = rcosdesign(app.beta, app.span, app.sps, "sqrt");
            app.hh = conv(app.h, app.h);
            app.messageUS = upsample(app.message, app.tau*app.sps);
            app.messagerx = conv(app.messageUS, app.hh);
            plot(app.UIAxes, 0:length(app.messagerx)-1, app.messagerx)
            plot(app.UIAxes2, 0:length(app.hh)-1, app.hh)
        end

        % Callback function: not associated with a component
        function MessageEditFieldValueChanged(app, event)
            app.message = 1-2*fliplr(de2bi(app.MessageSpinner.Value, 11)); %#ok<ST2NM>
            app.messageUS = upsample(app.message, app.tau*app.sps);
            app.messagerx = conv(app.messageUS, app.hh);
            plot(app.UIAxes, 0:length(app.messagerx)-1, app.messagerx)
            plot(app.UIAxes2, 0:length(app.hh)-1, app.hh)
        end

        % Value changed function: MessageSpinner
        function MessageSpinnerValueChanged(app, event)
            app.message = 1-2*fliplr(de2bi(app.MessageSpinner.Value, 11)); %#ok<ST2NM>
            app.messageUS = upsample(app.message, app.tau*app.sps);
            app.messagerx = conv(app.messageUS, app.hh);
            plot(app.UIAxes, 0:length(app.messagerx)-1, app.messagerx)
            plot(app.UIAxes2, 0:length(app.hh)-1, app.hh)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Signal', 'Interpreter', 'latex')
            xlabel(app.UIAxes, 'Samples', 'Interpreter', 'latex')
            ylabel(app.UIAxes, 'Amplitude', 'Interpreter', 'latex')
            zlabel(app.UIAxes, 'Z', 'Interpreter', 'latex')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Pulse')
            xlabel(app.UIAxes2, 'Samples')
            ylabel(app.UIAxes2, 'Amplitude')
            zlabel(app.UIAxes2, 'Z')
            app.tauEditFieldLabel = uilabel(app.UIFigure);
            app.tauEditFieldLabel.Text = '$\tau$';
            app.tauEditField = uieditfield(app.UIFigure, 'numeric');
            app.tauEditField.Limits = [0 1];
            app.tauEditField.ValueDisplayFormat = '%1.2f';
            app.tauEditField.AllowEmpty = 'on';
            app.tauEditField.ValueChangedFcn = createCallbackFcn(app, @tauEditFieldValueChanged, true);
            app.tauEditField.Value = 0.8;
            app.RolloffEditFieldLabel = uilabel(app.UIFigure);
            app.RolloffEditFieldLabel.Text = 'Roll-off';
            app.RolloffEditField = uieditfield(app.UIFigure, 'numeric');
            app.RolloffEditField.Limits = [0 1];
            app.RolloffEditField.ValueChangedFcn = createCallbackFcn(app, @RolloffEditFieldValueChanged, true);
            app.RolloffEditField.Value = 0.3;
            app.SamplespersymbolEditFieldLabel = uilabel(app.UIFigure);
            app.SamplespersymbolEditFieldLabel.Text = 'Samples per symbol';
            app.SamplespersymbolEditField = uieditfield(app.UIFigure, 'numeric');
            app.SamplespersymbolEditField.Limits = [10 Inf];
            app.SamplespersymbolEditField.RoundFractionalValues = 'on';
            app.SamplespersymbolEditField.ValueDisplayFormat = '%d';
            app.SamplespersymbolEditField.ValueChangedFcn = createCallbackFcn(app, @SamplespersymbolEditFieldValueChanged, true);
            app.SamplespersymbolEditField.Value = 10;
            app.FilterspanEditFieldLabel = uilabel(app.UIFigure);
            app.FilterspanEditFieldLabel.Text = 'Filter span';
            app.FilterspanEditField = uieditfield(app.UIFigure, 'numeric');
            app.FilterspanEditField.Limits = [3 Inf];
            app.FilterspanEditField.RoundFractionalValues = 'on';
            app.FilterspanEditField.ValueDisplayFormat = '%d';
            app.FilterspanEditField.ValueChangedFcn = createCallbackFcn(app, @FilterspanEditFieldValueChanged, true);
            app.FilterspanEditField.Value = 3;
            app.MajorGridsCheckBox = uicheckbox(app.UIFigure);
            app.MajorGridsCheckBox.ValueChangedFcn = createCallbackFcn(app, @MajorGridsCheckBoxValueChanged, true);
            app.MajorGridsCheckBox.Text = 'Major Grids';
            app.MajorGridsCheckBox.Value = true;
            app.MinorGridsCheckBox = uicheckbox(app.UIFigure);
            app.MinorGridsCheckBox.ValueChangedFcn = createCallbackFcn(app, @MinorGridsCheckBoxValueChanged, true);
            app.MinorGridsCheckBox.Text = 'Minor Grids';
            app.MinorGridsCheckBox.Value = true;
            app.MessageSpinnerLabel = uilabel(app.UIFigure);
            app.MessageSpinnerLabel.Text = 'Message';
            app.MessageSpinner = uispinner(app.UIFigure);
            app.MessageSpinner.Limits = [0 2047];
            app.MessageSpinner.ValueChangedFcn = createCallbackFcn(app, @MessageSpinnerValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = plotter

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end